classdef VFM_Dashboard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        appLamp                matlab.ui.control.Lamp
        appLampLabel           matlab.ui.control.Label
        OilRateBPDGauge        matlab.ui.control.Gauge
        OilRateBPDGaugeLabel   matlab.ui.control.Label
        StartMonitoringButton  matlab.ui.control.Button
        appAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartMonitoringButton
        function StartMonitoringButtonPushed(app, event)
            % 1. Load the processed analytics data
            if ~exist('Analytics_Results.mat', 'file')
                uialert(app.UIFigure, 'Please run Data_Analytics.m first!', 'Data Missing');
                return;
            end
            savedData = load('Analytics_Results.mat');
            AnalyticsResult = savedData.AnalyticsResult;

            Time = AnalyticsResult.Time;
            Oil_Flow = AnalyticsResult.Oil_Flow_Rate;
            Water_Flow = AnalyticsResult.Water_Flow_Rate;
            WLR = AnalyticsResult.Calculated_WLR;

            % 2. Prepare the axes for live animation
            cla(app.appAxes); 
            grid(app.appAxes, 'on');

            oilLine = animatedline(app.appAxes, 'Color', 'g', 'LineWidth', 1.5, 'DisplayName', 'Net Oil');
            waterLine = animatedline(app.appAxes, 'Color', 'b', 'LineWidth', 1.5, 'DisplayName', 'Water');

            title(app.appAxes, 'Real-time Production Analytics');
            xlabel(app.appAxes, 'Time (Hours)'); ylabel(app.appAxes, 'Flow Rate (BPD)');
            legend(app.appAxes, 'show');

            % 3. Simulation Loop to update dashboard in real-time
            step = 50; 

            % Dynamic Instrumentation Calibration (Adjust gauge limit based on max production)
            app.OilRateBPDGauge.Limits = [0, max(Oil_Flow) + 50];

            for i = 1:step:length(Time)

                % Update Gauge with current net oil rate
                app.OilRateBPDGauge.Value = Oil_Flow(i);

                % Force graphics engine to refresh the interface layout
                drawnow;

                % Add points to the live animated lines
                addpoints(oilLine, Time(i), Oil_Flow(i));
                addpoints(waterLine, Time(i), Water_Flow(i));

                % Dynamic Health Check via the Lamp (WLR Alert)
                if WLR(i) > 0.40
                    app.appLamp.Color = 'red';
                else
                    app.appLamp.Color = 'green';
                end

                % Controlled execution delay for real-time visualization playback
                pause(0.2); 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.appAxes = uiaxes(app.UIFigure);
            title(app.appAxes, 'Real-time Production Performance')
            xlabel(app.appAxes, 'X')
            ylabel(app.appAxes, 'Y')
            zlabel(app.appAxes, 'Z')
            app.StartMonitoringButton = uibutton(app.UIFigure, 'push');
            app.StartMonitoringButton.ButtonPushedFcn = createCallbackFcn(app, @StartMonitoringButtonPushed, true);
            app.StartMonitoringButton.Text = 'Start Monitoring';
            app.OilRateBPDGaugeLabel = uilabel(app.UIFigure);
            app.OilRateBPDGaugeLabel.Text = 'OilRateBPDGauge';
            app.OilRateBPDGauge = uigauge(app.UIFigure, 'circular');
            app.appLampLabel = uilabel(app.UIFigure);
            app.appLampLabel.Text = 'Lamp';
            app.appLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VFM_Dashboard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end